classdef WF_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ONCheckBox_3                 matlab.ui.control.CheckBox
        LEDLabel_3                   matlab.ui.control.Label
        RecordingcontinuousCheckBox  matlab.ui.control.CheckBox
        msLabel                      matlab.ui.control.Label
        SetParamsButton              matlab.ui.control.Button
        Switch_2                     matlab.ui.control.Switch
        Switch                       matlab.ui.control.Switch
        CameraLabel                  matlab.ui.control.Label
        LEDLabel_2                   matlab.ui.control.Label
        LEDLabel                     matlab.ui.control.Label
        ONCheckBox_2                 matlab.ui.control.CheckBox
        ONCheckBox                   matlab.ui.control.CheckBox
        DirectoryEditField           matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        ExposureEditField            matlab.ui.control.NumericEditField
        ExposureEditFieldLabel       matlab.ui.control.Label
        TriggermodeDropDown          matlab.ui.control.DropDown
        TriggermodeDropDownLabel     matlab.ui.control.Label
        FramerateEditField           matlab.ui.control.NumericEditField
        FramerateEditFieldLabel      matlab.ui.control.Label
        HeightEditField              matlab.ui.control.NumericEditField
        HeightEditFieldLabel         matlab.ui.control.Label
        WidthEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel          matlab.ui.control.Label
        YoriginEditField             matlab.ui.control.NumericEditField
        YoriginEditFieldLabel        matlab.ui.control.Label
        XoriginEditField             matlab.ui.control.NumericEditField
        XoriginEditFieldLabel        matlab.ui.control.Label
        CameramodeDropDown           matlab.ui.control.DropDown
        CameramodeDropDownLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        CameraRoot = '';
        CameraMode = '';
        CameraXpos = [];
        CameraYpos = [];
        CameraWidth = [];
        CameraHeigth = [];
        CameraFrameRate = [];
        CameraExposure = [];
        CameraTriggerMode = '';
        RecordingContinuous = [];

        LED488 = [];
        LED405 = [];

    end
    
    properties (Access = private)
        LED567 % Description
    end

    
    methods (Access = public)
        
        function results = update_WF_info(app)
            
            global WF_FileInfo

            app.CameraRoot = app.DirectoryEditField.Value;
            app.CameraMode = app.CameramodeDropDown.Value;
            app.CameraXpos = app.XoriginEditField.Value;
            app.CameraYpos = app.YoriginEditField.Value;
            app.CameraWidth = app.WidthEditField.Value;
            app.CameraHeigth = app.HeightEditField.Value;
            app.CameraFrameRate = app.FramerateEditField.Value;
            app.CameraExposure = app.ExposureEditField.Value;
            app.CameraTriggerMode = app.TriggermodeDropDown.Value;
            app.RecordingContinuous = app.RecordingcontinuousCheckBox.Value;
    
            app.LED488 = app.ONCheckBox.Value;
            app.LED405 = app.ONCheckBox_2.Value;
            app.LED567 = app.ONCheckBox_3.Value;

            WF_FileInfo.CameraRoot = app.CameraRoot;
            WF_FileInfo.CameraMode = app.CameraMode;
            WF_FileInfo.CameraXpos = app.CameraXpos;
            WF_FileInfo.CameraYpos = app.CameraYpos;
            WF_FileInfo.CameraWidth = app.CameraWidth;
            WF_FileInfo.CameraHeight = app.CameraHeigth;
            WF_FileInfo.CameraFrameRate = app.CameraFrameRate;
            WF_FileInfo.CameraExposure = app.CameraExposure/1000;
            WF_FileInfo.CameraTriggerMode = app.CameraTriggerMode;
            WF_FileInfo.RecordingContinuous = app.RecordingContinuous;
    
            WF_FileInfo.LED488 = app.LED488;
            WF_FileInfo.LED405 = app.LED405;
            WF_FileInfo.LED567 = app.LED567;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function assign_name(app)
            assignin('base', 'wf', app);
        end

        % Drop down opening function: CameramodeDropDown
        function CameramodeDropDownOpening(app, event)
            a = 1;
        end

        % Value changed function: CameramodeDropDown
        function CameramodeDropDownValueChanged(app, event)
            value = app.CameramodeDropDown.Value;
            app.CameraMode = value;
        end

        % Value changed function: XoriginEditField
        function XoriginEditFieldValueChanged(app, event)
            value = app.XoriginEditField.Value;
            app.CameraXpos = value;
        end

        % Value changed function: YoriginEditField
        function YoriginEditFieldValueChanged(app, event)
            value = app.YoriginEditField.Value;
            app.CameraYpos = value;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.CameraWidth = value;
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.CameraHeigth = value;
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            value = app.FramerateEditField.Value;
            app.CameraFrameRate = value;
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            app.CameraExposure = value;
        end

        % Value changed function: TriggermodeDropDown
        function TriggermodeDropDownValueChanged(app, event)
            value = app.TriggermodeDropDown.Value;
            app.CameraTriggerMode = value;
        end

        % Value changed function: ONCheckBox
        function ONCheckBoxValueChanged(app, event)
            value = app.ONCheckBox.Value;
            app.ONCheckBox = value;
        end

        % Value changed function: ONCheckBox_2
        function ONCheckBox_2ValueChanged(app, event)
            value = app.ONCheckBox_2.Value;
            app.ONCheckBox_2 = value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            WF_S = daq('ni');
            addoutput(WF_S, 'Dev3', 'ao1', 'Voltage');% Camera Frames LED 1
            addoutput(WF_S, 'Dev3', 'ao2', 'Voltage');% Camera Frames LED 2

            if value == 1
                WF_S.write([5,0])
            else
                WF_S.write([0,0])
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;

            WF_S = daq('ni');
            addoutput(WF_S, 'Dev3', 'ao1', 'Voltage');% Camera Frames LED 1
            addoutput(WF_S, 'Dev3', 'ao2', 'Voltage');% Camera Frames LED 2
            disp(value)
            if value == 1
                WF_S.write([0,5])
            else
                WF_S.write([0,0])
            end
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            value = app.DirectoryEditField.Value;
            app.CameraRoot = value;
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            update_WF_info(app);

        end

        % Value changed function: RecordingcontinuousCheckBox
        function RecordingcontinuousCheckBoxValueChanged(app, event)
            value = app.RecordingcontinuousCheckBox.Value;
            app.RecordingContinuous = value;
            
        end

        % Value changed function: ONCheckBox_3
        function ONCheckBox_3ValueChanged(app, event)
            value = app.ONCheckBox_3.Value;
            app.ONCheckBox_2 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameramodeDropDownLabel = uilabel(app.UIFigure);
            app.CameramodeDropDownLabel.Text = 'Camera mode';
            app.CameramodeDropDown = uidropdown(app.UIFigure);
            app.CameramodeDropDown.Items = {'MONO16_BIN4x4_512x512_FastMode'};
            app.CameramodeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CameramodeDropDownOpening, true);
            app.CameramodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CameramodeDropDownValueChanged, true);
            app.CameramodeDropDown.Value = 'MONO16_BIN4x4_512x512_FastMode';
            app.XoriginEditFieldLabel = uilabel(app.UIFigure);
            app.XoriginEditFieldLabel.Text = 'X origin';
            app.XoriginEditField = uieditfield(app.UIFigure, 'numeric');
            app.XoriginEditField.RoundFractionalValues = 'on';
            app.XoriginEditField.ValueChangedFcn = createCallbackFcn(app, @XoriginEditFieldValueChanged, true);
            app.XoriginEditField.Value = 60;
            app.YoriginEditFieldLabel = uilabel(app.UIFigure);
            app.YoriginEditFieldLabel.Text = 'Y origin';
            app.YoriginEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoriginEditField.RoundFractionalValues = 'on';
            app.YoriginEditField.ValueChangedFcn = createCallbackFcn(app, @YoriginEditFieldValueChanged, true);
            app.YoriginEditField.Value = 130;
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.RoundFractionalValues = 'on';
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 320;
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.RoundFractionalValues = 'on';
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 250;
            app.FramerateEditFieldLabel = uilabel(app.UIFigure);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramerateEditField.Limits = [1 100];
            app.FramerateEditField.RoundFractionalValues = 'on';
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Value = 100;
            app.TriggermodeDropDownLabel = uilabel(app.UIFigure);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.UIFigure);
            app.TriggermodeDropDown.Items = {'EdgeTrigger', 'SynchronousReadoutTrigger'};
            app.TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggermodeDropDownValueChanged, true);
            app.TriggermodeDropDown.Value = 'EdgeTrigger';
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ExposureEditField.Value = 2.5;
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.DirectoryEditField.Value = 'C:\Experiment\WideFieldImaging\data\';
            app.ONCheckBox = uicheckbox(app.UIFigure);
            app.ONCheckBox.ValueChangedFcn = createCallbackFcn(app, @ONCheckBoxValueChanged, true);
            app.ONCheckBox.Text = '488 ON';
            app.ONCheckBox.Value = true;
            app.ONCheckBox_2 = uicheckbox(app.UIFigure);
            app.ONCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ONCheckBox_2ValueChanged, true);
            app.ONCheckBox_2.Text = '405 ON';
            app.ONCheckBox_2.Value = true;
            app.LEDLabel = uilabel(app.UIFigure);
            app.LEDLabel.Text = '488 LED';
            app.LEDLabel_2 = uilabel(app.UIFigure);
            app.LEDLabel_2.Text = '405 LED';
            app.CameraLabel = uilabel(app.UIFigure);
            app.CameraLabel.Text = 'Camera';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.ItemsData = [0 1];
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 0;
            app.SetParamsButton = uibutton(app.UIFigure, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Text = 'Set Params';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '(ms)';
            app.RecordingcontinuousCheckBox = uicheckbox(app.UIFigure);
            app.RecordingcontinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordingcontinuousCheckBoxValueChanged, true);
            app.RecordingcontinuousCheckBox.Text = 'Recording continuous';
            app.LEDLabel_3 = uilabel(app.UIFigure);
            app.LEDLabel_3.Text = '567 LED';
            app.ONCheckBox_3 = uicheckbox(app.UIFigure);
            app.ONCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ONCheckBox_3ValueChanged, true);
            app.ONCheckBox_3.Text = '567 ON';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WF_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @assign_name)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end